classdef PlantCalculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PlantMetricCalculatorUIFigure  matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        SingleFileTab                  matlab.ui.container.Tab
        BrowseButton                   matlab.ui.control.Button
        spectrumAxes                   matlab.ui.control.UIAxes
        MeasurementsPanel              matlab.ui.container.Panel
        MicromolessecLabel             matlab.ui.control.Label
        PPFQuantaEditFieldLabel        matlab.ui.control.Label
        PPFQuanta                      matlab.ui.control.NumericEditField
        RadiantWattsLabel_2            matlab.ui.control.Label
        YPFRadiantPowerLabel           matlab.ui.control.Label
        YPFRadiant                     matlab.ui.control.NumericEditField
        ChromaticityxyLabel            matlab.ui.control.Label
        Chromaticity                   matlab.ui.control.EditField
        PPFQuantaEffLabel              matlab.ui.control.Label
        PPFQuantaWatt                  matlab.ui.control.NumericEditField
        MicromolesJLabel               matlab.ui.control.Label
        YPFRadiantEffLabel             matlab.ui.control.Label
        YPFRadiantWatt                 matlab.ui.control.NumericEditField
        RadiantWattsWLabel_2           matlab.ui.control.Label
        LumensLabel                    matlab.ui.control.Label
        LightOutputLabel               matlab.ui.control.Label
        LuxEdit                        matlab.ui.control.NumericEditField
        LightOutputEffEditFieldLabel   matlab.ui.control.Label
        LightOutputWatt                matlab.ui.control.NumericEditField
        LumensWLabel                   matlab.ui.control.Label
        RadiantWattsLabel              matlab.ui.control.Label
        PPFRadiantPowerLabel           matlab.ui.control.Label
        PPFRadiant                     matlab.ui.control.NumericEditField
        RadiantWattsWLabel             matlab.ui.control.Label
        PPFRadiantEffLabel             matlab.ui.control.Label
        PPFRadiantWatt                 matlab.ui.control.NumericEditField
        MicromolessecLabel_2           matlab.ui.control.Label
        YPFQuantaEditFieldLabel        matlab.ui.control.Label
        YPFQuanta                      matlab.ui.control.NumericEditField
        MicromolesJLabel_2             matlab.ui.control.Label
        YPFQuantaEffLabel              matlab.ui.control.Label
        YPFQuantaWatt                  matlab.ui.control.NumericEditField
        AddToGroupTableButton          matlab.ui.control.Button
        unknownLabel                   matlab.ui.control.Label
        PSSLabel                       matlab.ui.control.Label
        PSSQuanta                      matlab.ui.control.NumericEditField
        unknownWLabel                  matlab.ui.control.Label
        PSSEffLabel                    matlab.ui.control.Label
        PSSWatt                        matlab.ui.control.NumericEditField
        HzLabel                        matlab.ui.control.Label
        RCRLabel                       matlab.ui.control.Label
        RCRQuanta                      matlab.ui.control.NumericEditField
        Label                          matlab.ui.control.Label
        PPFofTotalLabel                matlab.ui.control.Label
        RCRWatt                        matlab.ui.control.NumericEditField
        MultiplierLable                matlab.ui.control.Label
        SpectrumFilePathLabel          matlab.ui.control.Label
        Filepath                       matlab.ui.control.EditField
        WattageEditFieldLabel          matlab.ui.control.Label
        Wattage                        matlab.ui.control.NumericEditField
        IESBrowseButton                matlab.ui.control.Button
        IESFilePathLabel               matlab.ui.control.Label
        IESFilePath                    matlab.ui.control.EditField
        CostLabel                      matlab.ui.control.Label
        Cost                           matlab.ui.control.NumericEditField
        ImgBrowseButton                matlab.ui.control.Button
        PictureFilePathLabel           matlab.ui.control.Label
        ImgFilePath                    matlab.ui.control.EditField
        THDLabel                       matlab.ui.control.Label
        THD                            matlab.ui.control.NumericEditField
        VoltageLabel                   matlab.ui.control.Label
        Voltage                        matlab.ui.control.NumericEditField
        LampTypeDropDownLabel          matlab.ui.control.Label
        LampTypeDropDown               matlab.ui.control.DropDown
        CatalogEditFieldLabel          matlab.ui.control.Label
        CatalogEditField               matlab.ui.control.EditField
        ModelEditFieldLabel            matlab.ui.control.Label
        ModelEditField                 matlab.ui.control.EditField
        PowerFactorLabel               matlab.ui.control.Label
        powerFactor                    matlab.ui.control.NumericEditField
        BrandEditFieldLabel            matlab.ui.control.Label
        Brand                          matlab.ui.control.EditField
        XBarIESBrowseButton            matlab.ui.control.Button
        XBarIESFilePathLabel           matlab.ui.control.Label
        XBarIESFilePath                matlab.ui.control.EditField
        GroupFileTab                   matlab.ui.container.Tab
        DataTable                      matlab.ui.control.Table
        SaveLocationEditFieldLabel     matlab.ui.control.Label
        SaveLocation                   matlab.ui.control.EditField
        SaveAsButton                   matlab.ui.control.Button
        AddDataButton                  matlab.ui.control.Button
        GeneratePlantReportPDFsButton  matlab.ui.control.Button
    end


    properties (Access = public)
        spectrum % Description
        McCreeFromGraph = load('McCree1972.txt');
        h = 6.63e-34; % Joule seconds, Planck's constant
        c = 3.00e8; % meter/second
        Avo = 6.02e23; % Avogardo constant
        PSSRCRtable = readtable('PSS_RCR.txt','Delimiter', '\t');
        LastDir
        PPF
        PPFperW
        PPF_mole
        PPF_moleperW
        YPF
        YPFperW
        YPF_mole
        YPF_moleperW
        Lux
        LuxperW
        xChrom
        yChrom
        Tc
        PSS
        PSSperW
        RCR
        RCRperW
        Multiplier
        IESfile% Description
    end
    
    methods (Access = public)
        
        function updatePlot(app)
            wave = app.spectrum(:,1);
            specFlux = app.spectrum(:,2);
            app.Multiplier = max(specFlux);
            if app.Multiplier == 1
                qstring = 'This SPD is already relative. Is there a known multiplier?';
                title = 'Multiplier?';
                str1 = 'Yes';
                str2 = 'No';
                default = str1;
                button = questdlg(qstring,title,str1,str2,default);
                switch button
                    case str1
                        prompt = {'Spectrum Multiplier:'};
                        dlg_title = 'Multiplier';
                        num_lines = 1;
                        defaultans = {'.1687'};
                        multip = inputdlg(prompt,dlg_title,num_lines,defaultans);
                        app.Multiplier = str2double(multip{1});
                    case str2
                        app.Multiplier = max(specFlux);
                    otherwise
                        error('There was an error entering the multiplier.');
                end
            end
            app.MultiplierLable.Text = ['Multiplier = ',num2str(app.Multiplier,'%10.4f'),' (W/nm)'];
            normalSpecFlux = specFlux/app.Multiplier;
            area(app.spectrumAxes,wave,normalSpecFlux);
            xlabel(app.spectrumAxes,'Wavelength (nm)');
            ylabel(app.spectrumAxes,'Normalized Spectral Flux');
            updateMeasurments(app);
        end
        
        function updateMeasurments(app)
            %% Calculate Measurments
            wave = app.spectrum(:,1);
            specFlux = app.spectrum(:,2);
            watt = app.Wattage.Value;
            CalcPPF(app);
            CalcYPF(app);
            CalcPSS(app);
            CalcRCR(app);
            [app.Lux,app.xChrom,app.yChrom] = Lxy23Sep05([wave,specFlux]);
            app.Tc = CCT23Sep05(app.spectrum);
            app.LuxperW = app.Lux/watt;
            
            
            %% Show Measurments
            app.PPFRadiant.Value = app.PPF;
            app.PPFQuanta.Value = app.PPF_mole;
            app.YPFRadiant.Value = app.YPF;
            app.YPFQuanta.Value = app.YPF_mole;
            app.PPFRadiantWatt.Value = app.PPFperW;
            app.PPFQuantaWatt.Value = app.PPF_moleperW;
            app.YPFRadiantWatt.Value = app.YPFperW;
            app.YPFQuantaWatt.Value = app.YPF_moleperW;
            app.PSSQuanta.Value = app.PSS;
            app.PSSWatt.Value = app.PSSperW;
            app.RCRQuanta.Value = app.RCR;
            app.RCRWatt.Value = app.RCRperW;
            app.LuxEdit.Value = app.Lux;
            app.LightOutputWatt.Value = app.LuxperW;
            app.Chromaticity.Value = ['(',num2str(app.xChrom,3),',',num2str(app.yChrom,3),')'];
        end
        
        
        function CalcPPF(app)
            wave = app.spectrum(:,1);
            specFlux = app.spectrum(:,2);
            watt = app.Wattage.Value;
            q1 = find(wave>=400,1,'first');
            q2 = find(wave<=700,1,'last');
            
            app.PPF = trapz(wave(q1:q2),specFlux(q1:q2)); % Watts
            app.PPFperW = app.PPF/watt;
            app.PPF_mole = 1e6*trapz(wave(q1:q2),specFlux(q1:q2).*(wave(q1:q2)*1e-9)/(app.h*app.c*app.Avo)); % micromoles/s
            app.PPF_moleperW = app.PPF_mole/watt;
        end
        
        function CalcYPF(app)
            wave = app.spectrum(:,1);
            specFlux = app.spectrum(:,2);
            watt = app.Wattage.Value;
            McCreePhoton = interp1(app.McCreeFromGraph(:,1),app.McCreeFromGraph(:,2),wave,'linear',0.0);
            McCreeWatt = McCreePhoton.*((wave*1e-9)/(app.h*app.c*app.Avo)); % convert growth/photon to growth/joule
            McCreeWatt = McCreeWatt/max(McCreeWatt);
            
            app.YPF = trapz(wave,specFlux.*McCreeWatt); % Watts
            app.YPFperW = app.YPF/watt;
            app.YPF_mole = 1e6*trapz(wave,McCreePhoton.*specFlux.*(wave*1e-9))/(app.h*app.c*app.Avo); % micromoles/s
            app.YPF_moleperW = app.YPF_mole/watt;
        end
        
        function CalcRCR(app)
            wave = app.spectrum(:,1);
            specFlux = app.spectrum(:,2);
            q1 = find(wave>=300,1,'first');
            q2 = find(wave<=800,1,'last');
            conversionFactor = PPF_Conversion_Factor_05Apr2016(wave,specFlux);
            relSpecFlux = specFlux/max(specFlux);
            fluxMulti = (200)/sum(relSpecFlux);
            newFlux = relSpecFlux*fluxMulti;
            sigmaRed = interp1(app.PSSRCRtable.lambda,app.PSSRCRtable.sigma_r,wave,'linear',0.0);
            sigmaFarRed = interp1(app.PSSRCRtable.lambda,app.PSSRCRtable.sigma_fr,wave,'linear',0.0);
            PSSRed = trapz(wave(q1:q2),newFlux(q1:q2).*sigmaRed(q1:q2).*(wave(q1:q2)*1e-9)/(app.h*app.c*app.Avo));
            PSSFarRed = trapz(wave(q1:q2),newFlux(q1:q2).*sigmaFarRed(q1:q2).*(wave(q1:q2)*1e-9)/(app.h*app.c*app.Avo));
            PSS200 = PSSRed/(PSSRed + PSSFarRed);
            app.RCR = (PSS200).*(PSSFarRed);
            index = (wave(:)<700)&(wave(:)>400);
            app.RCRperW = (sum(specFlux(index))/sum(specFlux(:)))*100;
        end
        
        function CalcPSS(app)
            wave = app.spectrum(:,1);
            specFlux = app.spectrum(:,2);
            q1 = find(wave>=300,1,'first');
            q2 = find(wave<=800,1,'last');
            sigmaRed = interp1(app.PSSRCRtable.lambda,app.PSSRCRtable.sigma_r,wave,'linear',0.0);
            sigmaFarRed = interp1(app.PSSRCRtable.lambda,app.PSSRCRtable.sigma_fr,wave,'linear',0.0);
            PSSRed = trapz(wave(q1:q2),specFlux(q1:q2).*sigmaRed(q1:q2).*(wave(q1:q2)*1e-9)/(app.h*app.c*app.Avo));
            PSSFarRed = trapz(wave(q1:q2),specFlux(q1:q2).*sigmaFarRed(q1:q2).*(wave(q1:q2)*1e-9)/(app.h*app.c*app.Avo));
            app.PSS = PSSRed/(PSSRed + PSSFarRed);
            app.PSSperW = app.PSS/app.Wattage.Value;
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: AddDataButton
        function AddDataButtonPushed(app, event)
            if isempty(app.LastDir)
                [FileName,PathName,FilterSpec] = uigetfile({'*.*'},'Select Spectrums', 'MultiSelect', 'on');
            else
                [FileName,PathName,FilterSpec] = uigetfile({[app.LastDir,'*.*']},'Select Spectrums', 'MultiSelect', 'on');
            end
            if FilterSpec ~= 0
                if ischar(FileName)
                    FileName = {FileName};
                end
                for i = 1:length(FileName)
                    app.Filepath.Value = fullfile(PathName,FileName{i});
                    app.spectrum = load(fullfile(PathName,FileName{i}));
                    app.LastDir = PathName;
                    if isempty(app.LastDir)
                        [iesFileName,iesPathName,iesFilterSpec] = uigetfile({'*.*'},['Select ',FileName{i},'IES file'], 'MultiSelect', 'off');
                    else
                        [iesFileName,iesPathName,iesFilterSpec] = uigetfile({[app.LastDir,'*.*']},['Select ',FileName{i},'IES file'], 'MultiSelect', 'off');
                    end
                    if iesFilterSpec ~= 0
                        if ischar(iesFileName)
                            if isempty(app.LastDir)
                                [imgFileName,imgPathName,imgFilterSpec] = uigetfile({'*.*'},['Select ',FileName{i},'picture file'], 'MultiSelect', 'off');
                            else
                                [imgFileName,imgPathName,imgFilterSpec] = uigetfile({[app.LastDir,'*.*']},['Select ',FileName{i},'Picture file'], 'MultiSelect', 'off');
                            end
                            if imgFilterSpec ~= 0
                                if ischar(imgFileName)
                                    app.ImgFilePath.Value = fullfile(imgPathName,imgFileName);
                                    app.IESFilePath.Value = fullfile(iesPathName,iesFileName);
                                    app.IESfile = IESFile(app.IESFilePath.Value);
                                    prompt = {'File Name:','Cost:','THD'};
                                    dlg_title = 'Missing Info';
                                    num_lines = 1;
                                    defaultans = {fullfile(PathName,FileName{i}),'100','100'};
                                    wattage = inputdlg(prompt,dlg_title,num_lines,defaultans);
                                    app.Wattage.Value = app.IESfile.InputWatts;
                                    updatePlot(app);
                                    app.Cost.Value =  str2double(wattage{3});
                                    tableData = app.DataTable.Data;
                                    if size(tableData) == [0,0]
                                        newLine = {'Units','IESFile','ImageFile','XBarFile','Brand','CatalogNumber',...
                                            'ModelName','US$','Volts','Watts','%','','Lamp',...
                                            'Radiant Watts','Radiant Watts/Watt','Micromoles/sec','Micromoles/sec/Watt',...
                                            'Radiant Watts','Radiant Watts/Watt','Micromoles/sec','Micromoles/sec/Watt',...
                                            'Lumens','Lumens/Watt','Unit','Unit/Watt','Unit','Unit/Watt','(x,y)'};
                                        app.DataTable.Data = newLine;
                                        newLine = {app.Filepath.Value,app.IESFilePath.Value,app.ImgFilePath.Value,app.XBarIESFilePath.Value,...
                                            app.Brand.Value,app.CatalogEditField.Value,app.ModelEditField.Value,app.Cost.Value,app.Voltage.Value,app.Wattage.Value,...
                                            app.THD.Value,app.powerFactor.Value,lampType,app.PPF,app.PPFperW,app.PPF_mole,app.PPF_moleperW,...
                                            app.YPF,app.YPFperW,app.YPF_mole,app.PPF_moleperW...
                                            app.Lux,app.LuxperW,app.PSS,app.PSSperW,app.RCR,app.RCRperW,app.Chromaticity.Value};
                                        app.DataTable.Data =[app.DataTable.Data; newLine];
                                    else
                                        newLine = {app.Filepath.Value,app.IESFilePath.Value,app.ImgFilePath.Value,app.XBarIESFilePath.Value,...
                                            app.Brand.Value,app.CatalogEditField.Value,app.ModelEditField.Value,app.Cost.Value,app.Voltage.Value,app.Wattage.Value,...
                                            app.THD.Value,app.powerFactor.Value,lampType,app.PPF,app.PPFperW,app.PPF_mole,app.PPF_moleperW,...
                                            app.YPF,app.YPFperW,app.YPF_mole,app.PPF_moleperW...
                                            app.Lux,app.LuxperW,app.PSS,app.PSSperW,app.RCR,app.RCRperW,app.Chromaticity.Value};
                                        app.DataTable.Data =[app.DataTable.Data; newLine];
                                    end
                                end
                            end
                        end
                    end
                end
            end
        end

        % Button pushed function: AddToGroupTableButton
        function AddToGroupTableButtonPushed(app, event)
            tableData = app.DataTable.Data;
            lampType = app.LampTypeDropDown.Value;
            if strcmpi(lampType, 'Select Lamp Type')
                errordlg('Please Select a Lamp Type.','Lamp Type');
            else
                if isempty(app.CatalogEditField.Value)
                    errordlg('Please Select a Lamp Type.','Lamp Type');
                else
                    if size(tableData) == [0,0]
                        newLine = {'Units','IESFile','ImageFile','XBarFile','Brand','CatalogNumber',...
                            'ModelName','US$','Volts','Watts','%','','Lamp',...
                            'Radiant Watts','Radiant Watts/Watt','Micromoles/sec','Micromoles/sec/Watt',...
                            'Radiant Watts','Radiant Watts/Watt','Micromoles/sec','Micromoles/sec/Watt',...
                            'Lumens','Lumens/Watt','Unit','Unit/Watt','Unit','Unit/Watt','(x,y)'};
                        app.DataTable.Data = newLine;
                        newLine = {app.Filepath.Value,app.IESFilePath.Value,app.ImgFilePath.Value,app.XBarIESFilePath.Value,...
                            app.Brand.Value,app.CatalogEditField.Value,app.ModelEditField.Value,app.Cost.Value,app.Voltage.Value,app.Wattage.Value,...
                            app.THD.Value,app.powerFactor.Value,lampType,app.PPF,app.PPFperW,app.PPF_mole,app.PPF_moleperW,...
                            app.YPF,app.YPFperW,app.YPF_mole,app.PPF_moleperW...
                            app.Lux,app.LuxperW,app.PSS,app.PSSperW,app.RCR,app.RCRperW,app.Chromaticity.Value};
                        app.DataTable.Data =[app.DataTable.Data; newLine];
                    else
                        if sum(strcmpi(tableData(:,1),app.Filepath.Value))
                            newLine = {app.Filepath.Value,app.IESFilePath.Value,app.ImgFilePath.Value,app.XBarIESFilePath.Value,...
                                app.Brand.Value,app.CatalogEditField.Value,app.ModelEditField.Value,app.Cost.Value,app.Voltage.Value,app.Wattage.Value,...
                                app.THD.Value,app.powerFactor.Value,lampType,app.PPF,app.PPFperW,app.PPF_mole,app.PPF_moleperW,...
                                app.YPF,app.YPFperW,app.YPF_mole,app.PPF_moleperW...
                                app.Lux,app.LuxperW,app.PSS,app.PSSperW,app.RCR,app.RCRperW,app.Chromaticity.Value};
                            app.DataTable.Data(strcmpi(tableData(:,1),app.Filepath.Value),:) = newLine;
                        else
                            newLine = {app.Filepath.Value,app.IESFilePath.Value,app.ImgFilePath.Value,app.XBarIESFilePath.Value,...
                                app.Brand.Value,app.CatalogEditField.Value,app.ModelEditField.Value,app.Cost.Value,app.Voltage.Value,app.Wattage.Value,...
                                app.THD.Value,app.powerFactor.Value,lampType,app.PPF,app.PPFperW,app.PPF_mole,app.PPF_moleperW,...
                                app.YPF,app.YPFperW,app.YPF_mole,app.PPF_moleperW...
                                app.Lux,app.LuxperW,app.PSS,app.PSSperW,app.RCR,app.RCRperW,app.Chromaticity.Value};
                            app.DataTable.Data =[app.DataTable.Data; newLine];
                        end
                    end
                end
            end
            
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            if isempty(app.LastDir)
                [FileName,PathName,FilterIndex] = uigetfile({'*.*'},'Select Spectrum');
            else
                [FileName,PathName,FilterIndex] = uigetfile({[app.LastDir,'*.*']},'Select Spectrum');
            end
            if FilterIndex == 1
                app.Filepath.Value = fullfile(PathName,FileName);
                app.spectrum = load(fullfile(PathName,FileName));
                app.LastDir = PathName;
                updatePlot(app);
            end
        end

        % Cell selection callback: DataTable
        function DataTableCellSelection(app, event)
            
        end

        % Value changed function: Filepath
        function FilepathValueChanged(app, event)
            value = app.Filepath.Value;
            if 2 == exist(value,'file')
                app.spectrum = load(value);
                updatePlot(app);
            end
        end

        % Button pushed function: GeneratePlantReportPDFsButton
        function GeneratePlantReportPDFsButtonPushed(app, event)
            % Add in the plant report function
            tableData = app.DataTable.Data;
            path = uigetdir(app.LastDir,'Save Location');
            app.LastDir = [fullfile(path),'\'];
%             PPFmax = max(tableData{:,16});
%             PPFperWmax = max([tableData{:,16}]./[tableData{:,10}]);
%             PPFmin = min(tableData{:,16});
%             PPFperWmin = min([tableData{:,16}./tableData{:,10}]);
            for i =2:size(tableData,1) %skips the units row
                data = tableData(i,:);
                %                     'PPFmax',PPFmax,...
%                     'PPFperWmax',PPFperWmax,...
%                     'PPFmin',PPFmin,...
%                     'PPFperWmin',PPFperWmin,...
                FixtureData = struct('Lamp',data{13},...
                    'Voltage',data{9},...
                    'PPF',data{16},...
                    'YPF',data{20},...
                    'PPFofTotal',[],...
                    'PSS',data{24},...
                    'Brand',data{5},...
                    'PowerFactor',data{12},...
                    'RCR',data{26},...
                    'ImagePath',data{3},...
                    'Product',data{7},...
                    'Catalog',data{6},...
                    'Cost',data{8},...
                    'THD',data{11},...
                    'spd',data{1},...
                    'Spectrum',load(data{1}),...
                    'ies',data{2},...
                    'IESdata',IESFile(data{2}),...
                    'Wattage',data{10});
                warning('off','MATLAB:print:UIControlsScaled');
                index = (FixtureData.Spectrum(:,1)<700)&(FixtureData.Spectrum(:,1)>400);
                FixtureData.PPFofTotal = (sum(FixtureData.Spectrum(index,2))/sum(FixtureData.Spectrum(:,2)))*100;
            %                 p1 = d12pack.PlantReport(FixtureData);
            %                 print('-opengl',p1.Figure,fullfile(path,[FixtureData.Catalog,'page1.pdf']),'-dpdf');
            %                 close(p1.Figure);
            %                 delete(p1);
                FixtureData.xBarIES = data{4};
                makerpt(FixtureData,fullfile(path,FixtureData.Catalog),'LRC_Hort_Metrics.pdftx');
                
            %                 p2 = d12pack.ColorUniformityReport(FixtureData,xBarIES);
            %                 
            %                 print('-painters',p2.Figure,fullfile(path,[FixtureData.Catalog,'page2.pdf']),'-dpdf');
            %                 close(p2.Figure);
            %                 delete(p2);
            %                 delete(fullfile(path,[FixtureData.Catalog,'.pdf']));
            %                 append_pdfs(fullfile(path,[FixtureData.Catalog,'.pdf']),fullfile(path,[FixtureData.Catalog,'page1.pdf']),fullfile(path,[FixtureData.Catalog,'page2.pdf']));
            %                 delete(fullfile(path,[FixtureData.Catalog,'page1.pdf']),fullfile(path,[FixtureData.Catalog,'page2.pdf']));
            end
        end

        % Button pushed function: IESBrowseButton
        function IESBrowseButtonPushed(app, event)
            if isempty(app.LastDir)
                [FileName,PathName,FilterIndex] = uigetfile({'*.*'},'Select IES File');
            else
                [FileName,PathName,FilterIndex] = uigetfile({[app.LastDir,'*.ies']},'Select IES File');
            end
            if FilterIndex == 1
                app.IESFilePath.Value = fullfile(PathName,FileName);
                app.IESfile = IESFile(app.IESFilePath.Value);
                app.Wattage.Value = app.IESfile.InputWatts;
                app.Wattage.BackgroundColor = [1 1 1];
                app.LastDir = PathName;
                updatePlot(app);
            end
        end

        % Button pushed function: ImgBrowseButton
        function ImgBrowseButtonPushed(app, event)
            if isempty(app.LastDir)
                [FileName,PathName,FilterIndex] = uigetfile({'*.*'},'Select Picture');
            else
                [FileName,PathName,FilterIndex] = uigetfile({[app.LastDir,'*.*']},'Select Picture');
            end
            if FilterIndex == 1
                app.ImgFilePath.Value = fullfile(PathName,FileName);
                app.LastDir = PathName;
                updatePlot(app);
            end
        end

        % Button pushed function: SaveAsButton
        function SaveAsButtonPushed(app, event)
            [FileName,PathName,FilterIndex] = uiputfile({'*.xlsx, *.xls','Excel File (*.xlsx, *.xls)';...
                '*.csv',    'Comma Delimincated File (*.csv)';...
                '*.txt',    'Tab Delimincated File (*.txt)';...
                '*.m',      'MATLAB Data File (*.mat)'},...
                'Save Data File','Plant Data Files.xlsx');
            fullFileName = fullfile(PathName, FileName);
            dataTable = cell2table(app.DataTable.Data,'VariableNames',app.DataTable.ColumnName);
            switch FilterIndex
                case 0
                    app.SaveLocation.Value = 'User Clicked Cancel';
                case 1
                    app.SaveLocation.Value = fullFileName;
                    writetable(dataTable,fullFileName);
                case 2
                    app.SaveLocation.Value = fullFileName;
                    writetable(dataTable,fullFileName,'Delimiter',',','QuoteStrings',true);
                case 3
                    app.SaveLocation.Value = fullFileName;
                    writetable(dataTable,fullFileName,'Delimiter','\t');
                case 4
                    app.SaveLocation.Value = fullFileName;
                    save(fullFileName,'dataTable');
                otherwise
                    app.SaveLocation.Value = 'There was an Error. Please try again.';
            end
        end

        % Value changed function: Wattage
        function WattageValueChanged(app, event)
            value = app.Wattage.Value;
            if value ~= 1
                app.Wattage.BackgroundColor = [1 1 1];
            else
                app.Wattage.BackgroundColor = [1 1 0];
            end
            updateMeasurments(app);
        end

        % Button pushed function: XBarIESBrowseButton
        function XBarIESBrowseButtonPushed(app, event)
            if isempty(app.LastDir)
                [FileName,PathName,FilterIndex] = uigetfile({'*.*'},'Select IES File');
            else
                [FileName,PathName,FilterIndex] = uigetfile({[app.LastDir,'*.ies']},'Select IES File');
            end
            if FilterIndex == 1
                app.XBarIESFilePath.Value = fullfile(PathName,FileName);
                app.LastDir = PathName;
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PlantMetricCalculatorUIFigure = uifigure;
            app.PlantMetricCalculatorUIFigure.Name = 'Plant Metric Calculator';
            app.TabGroup = uitabgroup(app.PlantMetricCalculatorUIFigure);
            app.SingleFileTab = uitab(app.TabGroup);
            app.SingleFileTab.Title = 'Single File';
            app.BrowseButton = uibutton(app.SingleFileTab, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.spectrumAxes = uiaxes(app.SingleFileTab);
            title(app.spectrumAxes, 'Spectral Power Distibution')
            xlabel(app.spectrumAxes, 'Wavelength (nm)')
            ylabel(app.spectrumAxes, 'Power')
            app.MeasurementsPanel = uipanel(app.SingleFileTab);
            app.MeasurementsPanel.Title = 'Measurements';
            app.MicromolessecLabel = uilabel(app.MeasurementsPanel);
            app.MicromolessecLabel.Text = 'Micromoles/sec';
            app.PPFQuantaEditFieldLabel = uilabel(app.MeasurementsPanel);
            app.PPFQuantaEditFieldLabel.Text = 'PPF Quanta';
            app.PPFQuanta = uieditfield(app.MeasurementsPanel, 'numeric');
            app.PPFQuanta.ValueDisplayFormat = '%5.3f';
            app.PPFQuanta.Editable = 'off';
            app.RadiantWattsLabel_2 = uilabel(app.MeasurementsPanel);
            app.RadiantWattsLabel_2.Text = 'Radiant Watts';
            app.YPFRadiantPowerLabel = uilabel(app.MeasurementsPanel);
            app.YPFRadiantPowerLabel.Text = 'YPF Radiant Power';
            app.YPFRadiant = uieditfield(app.MeasurementsPanel, 'numeric');
            app.YPFRadiant.ValueDisplayFormat = '%5.3f';
            app.YPFRadiant.Editable = 'off';
            app.ChromaticityxyLabel = uilabel(app.MeasurementsPanel);
            app.ChromaticityxyLabel.Text = 'Chromaticity(x,y)';
            app.Chromaticity = uieditfield(app.MeasurementsPanel, 'text');
            app.Chromaticity.Editable = 'off';
            app.PPFQuantaEffLabel = uilabel(app.MeasurementsPanel);
            app.PPFQuantaEffLabel.Text = 'PPF Quanta Eff.';
            app.PPFQuantaWatt = uieditfield(app.MeasurementsPanel, 'numeric');
            app.PPFQuantaWatt.ValueDisplayFormat = '%5.3f';
            app.PPFQuantaWatt.Editable = 'off';
            app.MicromolesJLabel = uilabel(app.MeasurementsPanel);
            app.MicromolesJLabel.Text = 'Micromoles/J';
            app.YPFRadiantEffLabel = uilabel(app.MeasurementsPanel);
            app.YPFRadiantEffLabel.Text = 'YPF Radiant Eff.';
            app.YPFRadiantWatt = uieditfield(app.MeasurementsPanel, 'numeric');
            app.YPFRadiantWatt.ValueDisplayFormat = '%5.3f';
            app.YPFRadiantWatt.Editable = 'off';
            app.RadiantWattsWLabel_2 = uilabel(app.MeasurementsPanel);
            app.RadiantWattsWLabel_2.Text = 'Radiant Watts/W';
            app.LumensLabel = uilabel(app.MeasurementsPanel);
            app.LumensLabel.Text = 'Lumens';
            app.LightOutputLabel = uilabel(app.MeasurementsPanel);
            app.LightOutputLabel.Text = 'Light Output';
            app.LuxEdit = uieditfield(app.MeasurementsPanel, 'numeric');
            app.LuxEdit.ValueDisplayFormat = '%5.3f';
            app.LuxEdit.Editable = 'off';
            app.LightOutputEffEditFieldLabel = uilabel(app.MeasurementsPanel);
            app.LightOutputEffEditFieldLabel.Text = 'Light Output Eff.';
            app.LightOutputWatt = uieditfield(app.MeasurementsPanel, 'numeric');
            app.LightOutputWatt.ValueDisplayFormat = '%5.3f';
            app.LightOutputWatt.Editable = 'off';
            app.LumensWLabel = uilabel(app.MeasurementsPanel);
            app.LumensWLabel.Text = 'Lumens/W';
            app.RadiantWattsLabel = uilabel(app.MeasurementsPanel);
            app.RadiantWattsLabel.Text = 'Radiant Watts';
            app.PPFRadiantPowerLabel = uilabel(app.MeasurementsPanel);
            app.PPFRadiantPowerLabel.Text = 'PPF Radiant Power';
            app.PPFRadiant = uieditfield(app.MeasurementsPanel, 'numeric');
            app.PPFRadiant.ValueDisplayFormat = '%5.3f';
            app.PPFRadiant.Editable = 'off';
            app.RadiantWattsWLabel = uilabel(app.MeasurementsPanel);
            app.RadiantWattsWLabel.Text = 'Radiant Watts/W';
            app.PPFRadiantEffLabel = uilabel(app.MeasurementsPanel);
            app.PPFRadiantEffLabel.Text = 'PPF Radiant Eff.';
            app.PPFRadiantWatt = uieditfield(app.MeasurementsPanel, 'numeric');
            app.PPFRadiantWatt.ValueDisplayFormat = '%5.3f';
            app.PPFRadiantWatt.Editable = 'off';
            app.MicromolessecLabel_2 = uilabel(app.MeasurementsPanel);
            app.MicromolessecLabel_2.Text = 'Micromoles/sec';
            app.YPFQuantaEditFieldLabel = uilabel(app.MeasurementsPanel);
            app.YPFQuantaEditFieldLabel.Text = 'YPF Quanta';
            app.YPFQuanta = uieditfield(app.MeasurementsPanel, 'numeric');
            app.YPFQuanta.ValueDisplayFormat = '%5.3f';
            app.YPFQuanta.Editable = 'off';
            app.MicromolesJLabel_2 = uilabel(app.MeasurementsPanel);
            app.MicromolesJLabel_2.Text = 'Micromoles/J';
            app.YPFQuantaEffLabel = uilabel(app.MeasurementsPanel);
            app.YPFQuantaEffLabel.Text = 'YPF Quanta Eff.';
            app.YPFQuantaWatt = uieditfield(app.MeasurementsPanel, 'numeric');
            app.YPFQuantaWatt.ValueDisplayFormat = '%5.3f';
            app.YPFQuantaWatt.Editable = 'off';
            app.AddToGroupTableButton = uibutton(app.MeasurementsPanel, 'push');
            app.AddToGroupTableButton.ButtonPushedFcn = createCallbackFcn(app, @AddToGroupTableButtonPushed, true);
            app.AddToGroupTableButton.Text = '  Add To Group Table  ';
            app.unknownLabel = uilabel(app.MeasurementsPanel);
            app.unknownLabel.Text = 'unknown';
            app.PSSLabel = uilabel(app.MeasurementsPanel);
            app.PSSLabel.Text = 'PSS';
            app.PSSQuanta = uieditfield(app.MeasurementsPanel, 'numeric');
            app.PSSQuanta.ValueDisplayFormat = '%5.3f';
            app.PSSQuanta.Editable = 'off';
            app.unknownWLabel = uilabel(app.MeasurementsPanel);
            app.unknownWLabel.Text = 'unknown/W';
            app.PSSEffLabel = uilabel(app.MeasurementsPanel);
            app.PSSEffLabel.Text = 'PSS Eff.';
            app.PSSWatt = uieditfield(app.MeasurementsPanel, 'numeric');
            app.PSSWatt.ValueDisplayFormat = '%5.3f';
            app.PSSWatt.Editable = 'off';
            app.HzLabel = uilabel(app.MeasurementsPanel);
            app.HzLabel.Text = 'Hz';
            app.RCRLabel = uilabel(app.MeasurementsPanel);
            app.RCRLabel.Text = 'RCR';
            app.RCRQuanta = uieditfield(app.MeasurementsPanel, 'numeric');
            app.RCRQuanta.ValueDisplayFormat = '%5.3f';
            app.RCRQuanta.Editable = 'off';
            app.Label = uilabel(app.MeasurementsPanel);
            app.Label.Text = '%';
            app.PPFofTotalLabel = uilabel(app.MeasurementsPanel);
            app.PPFofTotalLabel.Text = 'PPF%ofTotal';
            app.RCRWatt = uieditfield(app.MeasurementsPanel, 'numeric');
            app.RCRWatt.ValueDisplayFormat = '%5.3f';
            app.RCRWatt.Editable = 'off';
            app.MultiplierLable = uilabel(app.SingleFileTab);
            app.MultiplierLable.Text = 'Multiplier = N/A';
            app.SpectrumFilePathLabel = uilabel(app.SingleFileTab);
            app.SpectrumFilePathLabel.Text = 'Spectrum File Path';
            app.Filepath = uieditfield(app.SingleFileTab, 'text');
            app.Filepath.ValueChangedFcn = createCallbackFcn(app, @FilepathValueChanged, true);
            app.Filepath.Value = 'File Name...';
            app.WattageEditFieldLabel = uilabel(app.SingleFileTab);
            app.WattageEditFieldLabel.Text = 'Wattage';
            app.Wattage = uieditfield(app.SingleFileTab, 'numeric');
            app.Wattage.LowerLimitInclusive = 'off';
            app.Wattage.Limits = [0 Inf];
            app.Wattage.ValueDisplayFormat = '%5.0fW';
            app.Wattage.ValueChangedFcn = createCallbackFcn(app, @WattageValueChanged, true);
            app.Wattage.Value = 1;
            app.IESBrowseButton = uibutton(app.SingleFileTab, 'push');
            app.IESBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @IESBrowseButtonPushed, true);
            app.IESBrowseButton.Text = 'Browse';
            app.IESFilePathLabel = uilabel(app.SingleFileTab);
            app.IESFilePathLabel.Text = 'IES File Path';
            app.IESFilePath = uieditfield(app.SingleFileTab, 'text');
            app.IESFilePath.Value = 'File Name...';
            app.CostLabel = uilabel(app.SingleFileTab);
            app.CostLabel.Text = 'Cost';
            app.Cost = uieditfield(app.SingleFileTab, 'numeric');
            app.Cost.LowerLimitInclusive = 'off';
            app.Cost.Limits = [0 Inf];
            app.Cost.ValueDisplayFormat = '$%5.2f';
            app.Cost.Value = 100;
            app.ImgBrowseButton = uibutton(app.SingleFileTab, 'push');
            app.ImgBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @ImgBrowseButtonPushed, true);
            app.ImgBrowseButton.Text = 'Browse';
            app.PictureFilePathLabel = uilabel(app.SingleFileTab);
            app.PictureFilePathLabel.Text = 'Picture File Path';
            app.ImgFilePath = uieditfield(app.SingleFileTab, 'text');
            app.ImgFilePath.Value = 'File Name...';
            app.THDLabel = uilabel(app.SingleFileTab);
            app.THDLabel.Text = 'THD';
            app.THD = uieditfield(app.SingleFileTab, 'numeric');
            app.THD.LowerLimitInclusive = 'off';
            app.THD.Limits = [0 Inf];
            app.THD.ValueDisplayFormat = '%2.1f%%';
            app.THD.Value = 100;
            app.VoltageLabel = uilabel(app.SingleFileTab);
            app.VoltageLabel.Text = 'Voltage';
            app.Voltage = uieditfield(app.SingleFileTab, 'numeric');
            app.Voltage.LowerLimitInclusive = 'off';
            app.Voltage.Limits = [0 Inf];
            app.Voltage.ValueDisplayFormat = '%5.0fV';
            app.Voltage.Value = 120;
            app.LampTypeDropDownLabel = uilabel(app.SingleFileTab);
            app.LampTypeDropDownLabel.Text = 'Lamp Type';
            app.LampTypeDropDown = uidropdown(app.SingleFileTab);
            app.LampTypeDropDown.Items = {'Select Lamp Type', 'Incan.', 'LED', 'HPS', 'MH'};
            app.LampTypeDropDown.Editable = 'on';
            app.LampTypeDropDown.Value = 'Select Lamp Type';
            app.CatalogEditFieldLabel = uilabel(app.SingleFileTab);
            app.CatalogEditFieldLabel.Text = 'Catalog ';
            app.CatalogEditField = uieditfield(app.SingleFileTab, 'text');
            app.ModelEditFieldLabel = uilabel(app.SingleFileTab);
            app.ModelEditFieldLabel.Text = 'Model';
            app.ModelEditField = uieditfield(app.SingleFileTab, 'text');
            app.PowerFactorLabel = uilabel(app.SingleFileTab);
            app.PowerFactorLabel.Text = 'Power Factor';
            app.powerFactor = uieditfield(app.SingleFileTab, 'numeric');
            app.powerFactor.LowerLimitInclusive = 'off';
            app.powerFactor.Limits = [0 Inf];
            app.powerFactor.ValueDisplayFormat = '%2.1f';
            app.powerFactor.Value = 1;
            app.BrandEditFieldLabel = uilabel(app.SingleFileTab);
            app.BrandEditFieldLabel.Text = 'Brand';
            app.Brand = uieditfield(app.SingleFileTab, 'text');
            app.XBarIESBrowseButton = uibutton(app.SingleFileTab, 'push');
            app.XBarIESBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @XBarIESBrowseButtonPushed, true);
            app.XBarIESBrowseButton.Text = 'Browse';
            app.XBarIESFilePathLabel = uilabel(app.SingleFileTab);
            app.XBarIESFilePathLabel.Text = 'XBar IES File Path';
            app.XBarIESFilePath = uieditfield(app.SingleFileTab, 'text');
            app.XBarIESFilePath.Value = 'File Name...';
            app.GroupFileTab = uitab(app.TabGroup);
            app.GroupFileTab.Title = 'Group File';
            app.DataTable = uitable(app.GroupFileTab);
            app.DataTable.ColumnName = {'Spectrum_File'; 'IESFile'; 'ImageFile'; 'XBarFile'; 'Brand'; 'CatalogNum'; 'ProductName'; 'Cost'; 'Voltage'; 'Wattage'; 'THD'; 'PowerFactor'; 'LampType'; 'PPF_R'; 'PPF__RperW'; 'PPF_Q'; 'PPF_QperW'; 'YPF_R'; 'YPF_RperW'; 'YPF_Q'; 'YPF_QperW'; 'Lux'; 'Lux_perW'; 'PSS'; 'PSS_perW'; 'RCR'; 'RCR_perW'; 'Chromaticity'};
            app.DataTable.RowName = {};
            app.DataTable.ColumnEditable = [true false false false false false false false false false false false false];
            app.DataTable.CellSelectionCallback = createCallbackFcn(app, @DataTableCellSelection, true);
            app.SaveLocationEditFieldLabel = uilabel(app.GroupFileTab);
            app.SaveLocationEditFieldLabel.Text = 'Save Location';
            app.SaveLocation = uieditfield(app.GroupFileTab, 'text');
            app.SaveLocation.Value = 'Click Save As...';
            app.SaveAsButton = uibutton(app.GroupFileTab, 'push');
            app.SaveAsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAsButtonPushed, true);
            app.SaveAsButton.Text = 'Save As...';
            app.AddDataButton = uibutton(app.GroupFileTab, 'push');
            app.AddDataButton.ButtonPushedFcn = createCallbackFcn(app, @AddDataButtonPushed, true);
            app.AddDataButton.Text = 'Add Data';
            app.GeneratePlantReportPDFsButton = uibutton(app.GroupFileTab, 'push');
            app.GeneratePlantReportPDFsButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratePlantReportPDFsButtonPushed, true);
            app.GeneratePlantReportPDFsButton.Text = 'Generate Plant Report PDFs';
        end
    end

    methods (Access = public)

        % Construct app
        function app = PlantCalculator

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PlantMetricCalculatorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PlantMetricCalculatorUIFigure)
        end
    end
end